classdef ulgGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadFilesPanel                matlab.ui.container.Panel
        Load                          matlab.ui.control.Button
        ulgReadyLabel                 matlab.ui.control.Label
        StatsReady                    matlab.ui.control.Lamp
        Load_bag                      matlab.ui.control.Button
        bagReadyLabel                 matlab.ui.control.Label
        BagReady                      matlab.ui.control.Lamp
        OptionsPanel                  matlab.ui.container.Panel
        Exit                          matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        StatsPanel                    matlab.ui.container.Panel
        Max_u                         matlab.ui.control.Label
        Max_v                         matlab.ui.control.Label
        Max_alt                       matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        TabGroup                      matlab.ui.container.TabGroup
        PositionTab                   matlab.ui.container.Tab
        Alt                           matlab.ui.control.Button
        XYZ                           matlab.ui.control.Button
        PlotTrajectoryfromBagButton   matlab.ui.control.Button
        VelocityTab                   matlab.ui.container.Tab
        Vel                           matlab.ui.control.Button
        AccelerationTab               matlab.ui.container.Tab
        Accel                         matlab.ui.control.Button
        LowPassFilterCutOffFrequencyHzEditFieldLabel  matlab.ui.control.Label
        LPFFreq                       matlab.ui.control.EditField
        SetButton                     matlab.ui.control.Button
        ReadyLampLabel                matlab.ui.control.Label
        ReadyLamp                     matlab.ui.control.Lamp
        PlotControllerCommandsButton  matlab.ui.control.Button
        RangeTab                      matlab.ui.container.Tab
        PlotRange                     matlab.ui.control.Button
        ChooseIntervalButton          matlab.ui.control.Button
        PlotRangeBagvsTimeButton      matlab.ui.control.Button
        TgoTab                        matlab.ui.container.Tab
        PlotTgo                       matlab.ui.control.Button
        GuidanceLawSwitchLabel        matlab.ui.control.Label
        GuidanceLawSwitch             matlab.ui.control.Switch
        CompareTrajectoriesTab        matlab.ui.container.Tab
        Load1                         matlab.ui.control.Button
        Load2                         matlab.ui.control.Button
        ReadyLamp_2Label              matlab.ui.control.Label
        MAT1Ready                     matlab.ui.control.Lamp
        ReadyLamp_2Label_2            matlab.ui.control.Label
        MAT2Ready                     matlab.ui.control.Lamp
        CompareTrajectoriesButton     matlab.ui.control.Button
        mat1Label                     matlab.ui.control.Label
        MAT1Label                     matlab.ui.control.EditField
        mat2Label                     matlab.ui.control.Label
        MAT2Label                     matlab.ui.control.EditField
        TargetPanel_2                 matlab.ui.container.Panel
        xmLabel                       matlab.ui.control.Label
        Tx                            matlab.ui.control.NumericEditField
        ymLabel_2                     matlab.ui.control.Label
        Ty                            matlab.ui.control.NumericEditField
        zmLabel_2                     matlab.ui.control.Label
        Tz                            matlab.ui.control.NumericEditField
        VelxmsLabel                   matlab.ui.control.Label
        Tvx                           matlab.ui.control.NumericEditField
        VelymsLabel                   matlab.ui.control.Label
        Tvy                           matlab.ui.control.NumericEditField
        VelzmsLabel                   matlab.ui.control.Label
        Tvz                           matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        ulg_file % Description
        MATname
        max_u
        max_v
        max_h
        xmin
        xmax
        rosbag
        mat1
        mat2
    end
    
    methods (Access = private)
        
        function fileName = corretName(~,ulg_file)
            % The log file name, no file specifier required
            fileName_check = ulg_file;
            dotLocations = find(fileName_check == '.');
            if isempty(dotLocations)
               	% No dots at all found so just take entire name.
               	fileName = ulg_file;
            else
               	% Take up to , but not including, the first dot.
               	fileName = ulg_file(1:dotLocations(1)-1);
            end
            
        end
        
        function matName = addMATextention(~,ulg_file)
            matName = sprintf('%s.mat',ulg_file);
        end
        
        function bagName = addbagextention(~,ulg_file)
            bagName = sprintf('%s.bag',ulg_file);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            app.ulg_file = uigetfile('*.ulg');
            app.ulg_file = corretName(app,app.ulg_file);
            app.MATname = addMATextention(app,app.ulg_file);
            ulg2mat(app.ulg_file);
            [app.max_v,app.max_u,app.max_h] = getStats(app.MATname);
            str_v = sprintf('Maximum Velocity is %.3f [m/s]',app.max_v);
            str_u = sprintf('Maximum Acceleration is %.3f [m/s^2]',app.max_u);
            str_h = sprintf('Maximum Hight is %.3f [m]',app.max_h);
            app.Max_v.Text = str_v;
            app.Max_u.Text = str_u;
            app.Max_alt.Text = str_h;
            app.StatsReady.Color = 'green';
        end

        % Button pushed function: XYZ
        function XYZButtonPushed(app, event)
            xt = app.Tx.Value;
            yt = app.Ty.Value;
            zt = app.Tz.Value;
            plotXYZ(app.MATname,xt,yt,zt);
        end

        % Button pushed function: Alt
        function AltButtonPushed(app, event)
            plotAlt(app.MATname);
        end

        % Button pushed function: Accel
        function AccelButtonPushed(app, event)
            plotAccel(app.MATname,str2double(app.LPFFreq.Value));
        end

        % Button pushed function: Vel
        function VelButtonPushed(app, event)
            plotVel(app.MATname);
        end

        % Button pushed function: Exit
        function ExitButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.ReadyLamp.Color = 'green';
        end

        % Button pushed function: PlotTgo
        function PlotTgoButtonPushed(app, event)
            type = app.GuidanceLawSwitch.Value;
            plotTgobag(app.rosbag,type);
        end

        % Button pushed function: PlotRange
        function PlotRangeButtonPushed(app, event)
            R_e = [app.Tx.Value app.Ty.Value app.Tz.Value];
            V_e = [app.Tvx.Value app.Tvy.Value app.Tvz.Value];
            plotRange(app.MATname,R_e,V_e,0)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ulg_file = '';
            app.MATname = '';
            app.max_h = 0;
            app.max_u = 0;
            app.max_v = 0;
            app.StatsReady.Color = 'red';
            app.BagReady.Color = 'red';
        end

        % Button pushed function: ChooseIntervalButton
        function ChooseIntervalButtonPushed(app, event)
            [app.xmax,~] = ginput(1);
            R_e = [app.Tx.Value app.Ty.Value app.Tz.Value];
            V_e = [app.Tvx.Value app.Tvy.Value app.Tvz.Value];
            plotRange(app.MATname,R_e,V_e,app.xmax)
        end

        % Button pushed function: Load_bag
        function Load_bagButtonPushed(app, event)
            app.rosbag = uigetfile('*.bag');
            app.BagReady.Color = 'green';
        end

        % Button pushed function: Load1
        function Load1ButtonPushed(app, event)
            app.mat1 = uigetfile('*.mat');
            app.MAT1Ready.Color = 'green';
        end

        % Button pushed function: Load2
        function Load2ButtonPushed(app, event)
            app.mat2 = uigetfile('*.mat');
            app.MAT2Ready.Color = 'green';
        end

        % Button pushed function: CompareTrajectoriesButton
        function CompareTrajectoriesButtonPushed(app, event)
            str1 = app.MAT1Label.Value;
            str2 = app.MAT2Label.Value;
            compareTrajectory(app.mat1,str1,app.mat2,str2,app.Tx.Value,app.Ty.Value,app.Tz.Value);
        end

        % Button pushed function: PlotRangeBagvsTimeButton
        function PlotRangeBagvsTimeButtonPushed(app, event)
            plotRangeBag(app.rosbag);
        end

        % Button pushed function: PlotTrajectoryfromBagButton
        function PlotTrajectoryfromBagButtonPushed(app, event)
            plotTrajectoryBag(app.rosbag);
        end

        % Button pushed function: PlotControllerCommandsButton
        function PlotControllerCommandsButtonPushed(app, event)
            plotUcmdBag(app.rosbag);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LoadFilesPanel = uipanel(app.UIFigure);
            app.LoadFilesPanel.Title = 'Load Files';
            app.Load = uibutton(app.LoadFilesPanel, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = 'Load .ulg File';
            app.ulgReadyLabel = uilabel(app.LoadFilesPanel);
            app.ulgReadyLabel.Text = '.ulg Ready';
            app.StatsReady = uilamp(app.LoadFilesPanel);
            app.Load_bag = uibutton(app.LoadFilesPanel, 'push');
            app.Load_bag.ButtonPushedFcn = createCallbackFcn(app, @Load_bagButtonPushed, true);
            app.Load_bag.Text = 'Load .bag File';
            app.bagReadyLabel = uilabel(app.LoadFilesPanel);
            app.bagReadyLabel.Text = '.bag Ready';
            app.BagReady = uilamp(app.LoadFilesPanel);
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.Exit = uibutton(app.OptionsPanel, 'push');
            app.Exit.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.Exit.Text = 'Exit';
            app.ResetButton = uibutton(app.OptionsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StatsPanel = uipanel(app.UIFigure);
            app.StatsPanel.Title = 'Stats';
            app.Max_u = uilabel(app.StatsPanel);
            app.Max_u.Text = '';
            app.Max_v = uilabel(app.StatsPanel);
            app.Max_v.Text = '';
            app.Max_alt = uilabel(app.StatsPanel);
            app.Max_alt.Text = '';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'logo.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PositionTab = uitab(app.TabGroup);
            app.PositionTab.Title = 'Position';
            app.Alt = uibutton(app.PositionTab, 'push');
            app.Alt.ButtonPushedFcn = createCallbackFcn(app, @AltButtonPushed, true);
            app.Alt.Text = 'Plot Altitude from Baro';
            app.XYZ = uibutton(app.PositionTab, 'push');
            app.XYZ.ButtonPushedFcn = createCallbackFcn(app, @XYZButtonPushed, true);
            app.XYZ.Text = 'Plot X-Y-Z from GPS';
            app.PlotTrajectoryfromBagButton = uibutton(app.PositionTab, 'push');
            app.PlotTrajectoryfromBagButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTrajectoryfromBagButtonPushed, true);
            app.PlotTrajectoryfromBagButton.Text = 'Plot Trajectory from Bag';
            app.VelocityTab = uitab(app.TabGroup);
            app.VelocityTab.Title = 'Velocity';
            app.Vel = uibutton(app.VelocityTab, 'push');
            app.Vel.ButtonPushedFcn = createCallbackFcn(app, @VelButtonPushed, true);
            app.Vel.Text = 'Plot Velocities from GPS ';
            app.AccelerationTab = uitab(app.TabGroup);
            app.AccelerationTab.Title = 'Acceleration';
            app.Accel = uibutton(app.AccelerationTab, 'push');
            app.Accel.ButtonPushedFcn = createCallbackFcn(app, @AccelButtonPushed, true);
            app.Accel.Text = 'Plot Acceleration Data ';
            app.LowPassFilterCutOffFrequencyHzEditFieldLabel = uilabel(app.AccelerationTab);
            app.LowPassFilterCutOffFrequencyHzEditFieldLabel.Text = 'Low-Pass Filter Cut-Off Frequency [Hz] =';
            app.LPFFreq = uieditfield(app.AccelerationTab, 'text');
            app.SetButton = uibutton(app.AccelerationTab, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.ReadyLampLabel = uilabel(app.AccelerationTab);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.AccelerationTab);
            app.PlotControllerCommandsButton = uibutton(app.AccelerationTab, 'push');
            app.PlotControllerCommandsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotControllerCommandsButtonPushed, true);
            app.PlotControllerCommandsButton.Text = 'Plot Controller Commands';
            app.RangeTab = uitab(app.TabGroup);
            app.RangeTab.Title = 'Range';
            app.PlotRange = uibutton(app.RangeTab, 'push');
            app.PlotRange.ButtonPushedFcn = createCallbackFcn(app, @PlotRangeButtonPushed, true);
            app.PlotRange.Text = 'Plot Range vs Time';
            app.ChooseIntervalButton = uibutton(app.RangeTab, 'push');
            app.ChooseIntervalButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseIntervalButtonPushed, true);
            app.ChooseIntervalButton.Text = 'Choose Interval';
            app.PlotRangeBagvsTimeButton = uibutton(app.RangeTab, 'push');
            app.PlotRangeBagvsTimeButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRangeBagvsTimeButtonPushed, true);
            app.PlotRangeBagvsTimeButton.Text = 'Plot RangeBag vs Time';
            app.TgoTab = uitab(app.TabGroup);
            app.TgoTab.Title = 'Tgo';
            app.PlotTgo = uibutton(app.TgoTab, 'push');
            app.PlotTgo.ButtonPushedFcn = createCallbackFcn(app, @PlotTgoButtonPushed, true);
            app.PlotTgo.Text = 'Plot Tgo vs Time';
            app.GuidanceLawSwitchLabel = uilabel(app.TgoTab);
            app.GuidanceLawSwitchLabel.Text = 'Guidance Law';
            app.GuidanceLawSwitch = uiswitch(app.TgoTab, 'slider');
            app.GuidanceLawSwitch.Items = {'Interception', 'Soft Landing'};
            app.GuidanceLawSwitch.Value = 'Interception';
            app.CompareTrajectoriesTab = uitab(app.TabGroup);
            app.CompareTrajectoriesTab.Title = 'Compare Trajectories';
            app.Load1 = uibutton(app.CompareTrajectoriesTab, 'push');
            app.Load1.ButtonPushedFcn = createCallbackFcn(app, @Load1ButtonPushed, true);
            app.Load1.Text = 'Load .mat 1';
            app.Load2 = uibutton(app.CompareTrajectoriesTab, 'push');
            app.Load2.ButtonPushedFcn = createCallbackFcn(app, @Load2ButtonPushed, true);
            app.Load2.Text = 'Load .mat 2';
            app.ReadyLamp_2Label = uilabel(app.CompareTrajectoriesTab);
            app.ReadyLamp_2Label.Text = 'Ready';
            app.MAT1Ready = uilamp(app.CompareTrajectoriesTab);
            app.ReadyLamp_2Label_2 = uilabel(app.CompareTrajectoriesTab);
            app.ReadyLamp_2Label_2.Text = 'Ready';
            app.MAT2Ready = uilamp(app.CompareTrajectoriesTab);
            app.CompareTrajectoriesButton = uibutton(app.CompareTrajectoriesTab, 'push');
            app.CompareTrajectoriesButton.ButtonPushedFcn = createCallbackFcn(app, @CompareTrajectoriesButtonPushed, true);
            app.CompareTrajectoriesButton.Text = 'Compare Trajectories!';
            app.mat1Label = uilabel(app.CompareTrajectoriesTab);
            app.mat1Label.Text = '.mat 1 Label:';
            app.MAT1Label = uieditfield(app.CompareTrajectoriesTab, 'text');
            app.mat2Label = uilabel(app.CompareTrajectoriesTab);
            app.mat2Label.Text = '.mat 2 Label:';
            app.MAT2Label = uieditfield(app.CompareTrajectoriesTab, 'text');
            app.TargetPanel_2 = uipanel(app.UIFigure);
            app.TargetPanel_2.Title = 'Target';
            app.xmLabel = uilabel(app.TargetPanel_2);
            app.xmLabel.Text = ' x [m] =';
            app.Tx = uieditfield(app.TargetPanel_2, 'numeric');
            app.Tx.Value = 50;
            app.ymLabel_2 = uilabel(app.TargetPanel_2);
            app.ymLabel_2.Text = 'y [m] =';
            app.Ty = uieditfield(app.TargetPanel_2, 'numeric');
            app.Ty.Value = 50;
            app.zmLabel_2 = uilabel(app.TargetPanel_2);
            app.zmLabel_2.Text = 'z [m] =';
            app.Tz = uieditfield(app.TargetPanel_2, 'numeric');
            app.Tz.Value = 50;
            app.VelxmsLabel = uilabel(app.TargetPanel_2);
            app.VelxmsLabel.Text = 'Vel - x [m/s] =';
            app.Tvx = uieditfield(app.TargetPanel_2, 'numeric');
            app.VelymsLabel = uilabel(app.TargetPanel_2);
            app.VelymsLabel.Text = 'Vel - y [m/s] =';
            app.Tvy = uieditfield(app.TargetPanel_2, 'numeric');
            app.VelzmsLabel = uilabel(app.TargetPanel_2);
            app.VelzmsLabel.Text = 'Vel - z [m/s] =';
            app.Tvz = uieditfield(app.TargetPanel_2, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ulgGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end